classdef AgilentHPLC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Phase1Tab                      matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        Step1Label                     matlab.ui.control.Label
        ChooseDataTypeLabel            matlab.ui.control.Label
        DataType                       matlab.ui.control.DropDown
        Step2Label                     matlab.ui.control.Label
        LoadFilesButton                matlab.ui.control.Button
        Step7Label                     matlab.ui.control.Label
        SelectPeaksButton              matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        OptionalStep4aADVANCEDLabel    matlab.ui.control.Label
        ManualEditPeakButton           matlab.ui.control.Button
        Step3HighlightChromatogramFoldertouseforPeakSelectionLabel  matlab.ui.control.Label
        Generate3DVisualizationButton  matlab.ui.control.Button
        Step4Label                     matlab.ui.control.Label
        ChooseTimeIntervalthatRepresentsParentPeakLabel  matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        CloseAppButton                 matlab.ui.control.Button
        ListBox_2                      matlab.ui.control.ListBox
        Step6Label                     matlab.ui.control.Label
        ExportDataButton               matlab.ui.control.Button
        Step5Label                     matlab.ui.control.Label
        GenerateParentFractionButton   matlab.ui.control.Button
        AsymmetricParameterEditFieldLabel  matlab.ui.control.Label
        AsymmetricParameterEditField   matlab.ui.control.NumericEditField
        SmoothnessParameterEditFieldLabel  matlab.ui.control.Label
        SmoothnessParameterEditField   matlab.ui.control.NumericEditField
        OPTIONALStep2aADVANCEDLabel    matlab.ui.control.Label
        Step8Label                     matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        TextArea_2                     matlab.ui.control.TextArea
        CloseFiguresButton             matlab.ui.control.Button
        TextArea_3                     matlab.ui.control.TextArea
        TextArea_4                     matlab.ui.control.TextArea
        NoiseButton                    matlab.ui.control.Button
        OPTIONALStep3aADVANCEDLabel    matlab.ui.control.Label
        Phase2Tab                      matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UncorrectedPlasmaActivityButton  matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        InputParentFractiontxtpathButton  matlab.ui.control.Button
        EditField_2                    matlab.ui.control.EditField
        EditFieldPMOD                  matlab.ui.control.EditField
        ClearButton                    matlab.ui.control.Button
        CorrectBloodDataButton         matlab.ui.control.Button
        Step1InputUncorrectedPlasmaBloodFilePathsLabel  matlab.ui.control.Label
        Step2InputParentFractionFilePathtxtorbldLabel  matlab.ui.control.Label
        Step3CorrectPlasmaBloodFileLabel  matlab.ui.control.Label
        ListBox_4                      matlab.ui.control.ListBox
    end

    
    properties (Access = private)
         initializetoolbox;
         rootfolderpath;
         RadiotracerdataMap=containers.Map(); 
         UnprocessedRadiotracerdataMap=containers.Map();
         SelectedPeaksMap=containers.Map();
         AUCMap=containers.Map();
         PeaksNumberingConventionMap=containers.Map(); 
         amountofpeaks;
         UncorrectedPlasma;

    end
    
    methods (Access = private)
        
        function PreprocessRadiotracer(app,chromatogramfile)
            app.ShiftTracerDataAboveBaseline(chromatogramfile);
            app.DecayCorrectRadiotracer(app.DataType.Value,chromatogramfile);
            app.ApplySmoothingandAsymmetry(app.SmoothnessParameterEditField.Value,app.AsymmetricParameterEditField.Value,chromatogramfile);
        end
        
        function DecayCorrectRadiotracer(app,typeofisotope,chromatogramfile)
            if typeofisotope=='F-18'
                app.DecayCorrectF18(chromatogramfile);
                
            elseif typeofisotope=='C-11'
                app.DecayCorrectC11(chromatogramfile);
            else
                error('Could not apply proper decay correction to specified radiotracer.');
            end
        end
        
         %---------------------------------------------------------------------
         % Refer to http://www.turkupetcentre.net/petanalysis/decay.html
         % for derivation of correction function to apply 
         
         %Iterate through map and decay correct HPLC data
         function DecayCorrectC11(app,chromatogramfile)
             lamda=.034;
             tracerdata=app.RadiotracerdataMap(chromatogramfile);
             lamdatimeproduct=lamda.*(tracerdata.time);
             exponentterm=exp(lamdatimeproduct);
             decaycorrectedvalues=(tracerdata.tic.values).*exponentterm;
             tracerdata.tic.values=decaycorrectedvalues;
             app.RadiotracerdataMap(chromatogramfile)=tracerdata;
         end
         
         function DecayCorrectF18(app,chromatogramfile)
             lamda=.006;
             tracerdata=app.RadiotracerdataMap(chromatogramfile);
             lamdatimeproduct=lamda.*(tracerdata.time);
             exponentterm=exp(lamdatimeproduct);
             decaycorrectedvalues=(tracerdata.tic.values).*exponentterm;
             tracerdata.tic.values=decaycorrectedvalues;
             app.RadiotracerdataMap(chromatogramfile)=tracerdata;
         end
         
        %---------------------------------------------------------------------
        
        
        function ApplySmoothingandAsymmetry(app,smooth,asymmetry,chromatogramfile)
            tracerdata=app.RadiotracerdataMap(chromatogramfile);
            tracerdata=app.initializetoolbox.smooth(tracerdata,...
                'samples','all',...
                'ions','tic',...
                'smoothness',smooth,...
                'asymmetry',asymmetry);
            app.RadiotracerdataMap(chromatogramfile)=tracerdata;
        end
        
        
        function ShiftTracerDataAboveBaseline(app,chromatogramfile)
            tracerdata=app.UnprocessedRadiotracerdataMap(chromatogramfile);
            translationamount=abs(min(tracerdata.tic.values));
            tracerdata.tic.values=tracerdata.tic.values+translationamount;
            app.RadiotracerdataMap(chromatogramfile)=tracerdata;
        end
        
        function DisplayChromatogramFileNames(app)
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                [~,name,ext] = fileparts(chromatogramfile);
                app.ListBox.Items{i}=sprintf('%s%s',name,ext);
            end
        end
        
        function PlotChromatogramData(app)
            chromatogramfolder=app.ListBox.Value;
            chromatogramfile=fullfile(app.rootfolderpath,chromatogramfolder);
            isValiddata=contains(chromatogramfile,'Item');
            if ~isValiddata
                unprocesseddata=app.UnprocessedRadiotracerdataMap(chromatogramfile);
                processeddata=app.RadiotracerdataMap(chromatogramfile);
                
                cla(app.UIAxes,'reset');
                plot(app.UIAxes,unprocesseddata.time*60,unprocesseddata.tic.values,'Color','r');
                hold(app.UIAxes);
                plot(app.UIAxes,processeddata.time*60,processeddata.tic.values,'Color','b');
                chromatogramendtimeinsecs=round(processeddata.time(end))*60;
                app.UIAxes.XLim = [0 chromatogramendtimeinsecs];
                app.UIAxes.YLabel.String = 'Radioactivity';  
                
                underline = strfind(chromatogramfolder,'_');
                chromatogramfolder(underline)= '-'; %change for graphic reasons
                title(app.UIAxes, chromatogramfolder);
                legend(app.UIAxes,{'Unprocessed','Decay Corrected, Smoothed'},'Location','Best');
            end
        end
        
        function ApplyNoiseReduction(app)
            chromatogramfolder=app.ListBox.Value;
            chromatogramfile=fullfile(app.rootfolderpath,chromatogramfolder);
            isValiddata=contains(chromatogramfile,'Item');
            if ~isValiddata
                %Load data, and find time interval that is defined noise
                processeddata=app.RadiotracerdataMap(chromatogramfile);
                [~,name,~]=fileparts(chromatogramfile)
                temp = strfind(name,'_');
                name(temp)= '-';
                noiseinterval=app.SelectInterval(processeddata,name);
                if ~isempty(noiseinterval)
                    app.SubtractMeanNoisefromRadioactivityData(processeddata,noiseinterval)
                else
                    f = warningdlg('User did not select noise interval. Noise reduction not applied.');
                end
            end
            
        end
        
        function noiseintervalarray=SelectInterval(app,processeddata,plottitle)
            figure(1);
            plot(processeddata.time*60,processeddata.tic.values);
            title(plottitle)
            xlabel('Time (secs)');
            ylabel('Radioactivity');
            try
                [noiseintervalarray,~] = getpts();
            catch
                noiseintervalarray=[];
                return
            end
            close(figure(1))
        end
        
        function SubtractMeanNoisefromRadioactivityData(app,processeddata,noiseinterval)
            if length(noiseinterval)==2
                tempnoisestartpoint=noiseinterval(1);
                tempnoiseendpoint=noiseinterval(2);
                
                noisestartpoint=app.GetClosestIndexinVector(processeddata,tempnoisestartpoint);
                noiseendpoint=app.GetClosestIndexinVector(processeddata,tempnoiseendpoint);
                
                meannoise=mean(abs(processeddata.tic.values((noisestartpoint:noiseendpoint))));
                
                %Subtract noise from all radioactivity values;update to Map
                mapkeys=keys(app.RadiotracerdataMap);
                for i=1:length(mapkeys)
                    chromatogramfile=mapkeys{i};
                    tracerdata=app.RadiotracerdataMap(chromatogramfile);
                    tracerdata.tic.values=tracerdata.tic.values-meannoise;
                    
                    %Artificially 0 out any chromatgram values less than 0
                    tracerdata.tic.values(tracerdata.tic.values<0)=0;
                    app.RadiotracerdataMap(chromatogramfile)=tracerdata;
                end
                
            else
                f = errordlg('Choose two points (two clicks) for the definition of the noise interval. ');
                return
            end
            
        end
        
        function closestindex=GetClosestIndexinVector(app,processeddata,peakintervals)
                chosenindex=peakintervals;
                [ ~, index] = min(abs(processeddata.time*60-chosenindex));
                closestindex=find(processeddata.time==processeddata.time(index));
        end
                
        function GetChromatogramPeaks(app)
            chromatogramfolder=app.ListBox.Value;
            chromatogramfile=fullfile(app.rootfolderpath,chromatogramfolder);
            isValiddata=contains(chromatogramfile,'Item');
            if ~isValiddata
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                [~,name,~]=fileparts(chromatogramfile)
                temp = strfind(name,'_');
                name(temp)= '-';
                peakintervals=app.SelectInterval(tracerdata,name);
                if ~isempty(peakintervals)
                    %Error check user input
                    if mod(length(peakintervals),2)~=0
                        close all
                        f = errordlg('Uneven amount of endpoints specified. Please redo analysis and make sure to click an even amount of endpoints (ie 2 endpoints per peak)');
                        return
                    end
                    
                    %Store for manual peak correction
                    app.amountofpeaks=length(peakintervals)/2;
                    
                    peakstartpoints=peakintervals(1:2:end)';
                    peakendpoints= peakintervals(2:2:end)';
                    
                    mapkeys=keys(app.RadiotracerdataMap);
                    for i=1:length(mapkeys)
                        chromatogramfile=mapkeys{i};
                        tracerdata=app.RadiotracerdataMap(chromatogramfile);
                        
                        %Iterate through all peaks defined per chromatogram,
                        %and store into map
                        arrayofpeakstructs=struct('start',{},'end',{});
                        for j=1:length(peakstartpoints)
                            startofinterval=app.GetClosestIndexinVector(tracerdata,peakstartpoints(j));
                            endofinterval=app.GetClosestIndexinVector(tracerdata,peakendpoints(j));
                            
                            peak.start=startofinterval;
                            peak.end=endofinterval;
                            
                            arrayofpeakstructs(end+1)=peak;
                        end
                        app.SelectedPeaksMap(chromatogramfile)=arrayofpeakstructs;
                    end
                else
                    f = warndlg('User did not select any peak intervals. Please select manual defined peaks.');
                end
            end
        end
        
        function PlotSelectedChromatogramPeaks(app)
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                
                try
                    peaksforthischromatogram=app.SelectedPeaksMap(chromatogramfile);
                catch ME
                    warndlg('Peaks not selected. Please complete step 4.')
                    break
                end
                figure(i);
                plot(tracerdata.time*60,tracerdata.tic.values);
                [~,name,~]=fileparts(chromatogramfile)
                temp = strfind(name,'_');
                name(temp)= '-';
                title(name);
                xlabel('Time (secs)');
                ylabel('Radioactivity');
                xlim = [0 tracerdata.time(end)*60];
                hold on;
                
                %Shade/color AUC of peak definition
                for j=1:length(peaksforthischromatogram)
                    starttime=peaksforthischromatogram(j).start;
                    endtime=peaksforthischromatogram(j).end;
                    areatohighlight=area(tracerdata.time(starttime:endtime)*60,tracerdata.tic.values(starttime:endtime));
                end
 
            end
            
            
        end
        
        function DisplayPeakIntervalTimes(app)
            %We only need to pull out the peak time intervals for one
            %chromatogram, since the time intervals are consistent for all
            %chromatograms
            mapkeys=keys(app.RadiotracerdataMap);
            chromatogramfile=mapkeys{1};
            tracerdata=app.RadiotracerdataMap(chromatogramfile);
            peaksforthischromatogram=app.SelectedPeaksMap(chromatogramfile);
            for i=1:length(peaksforthischromatogram)
                starttime=peaksforthischromatogram(i).start;
                endtime=peaksforthischromatogram(i).end;
                boxtext=sprintf('Peak %i: %f-%f',i, tracerdata.time(starttime)*60,tracerdata.time(endtime)*60);
                app.ListBox_2.Items{i}=boxtext;
                app.PeaksNumberingConventionMap(boxtext)=i;
            end
        end
        
        function ManualPeakTimeIntervalEdit(app)
            chromatogramfolder=app.ListBox.Value;
            chromatogramfile=fullfile(app.rootfolderpath,chromatogramfolder);
            isValiddata=contains(chromatogramfile,'Item');
            if ~isValiddata
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                [~,name,~]=fileparts(chromatogramfile);
                temp = strfind(name,'_');
                name(temp)= '-';
                peakintervals=app.SelectInterval(tracerdata,name);
                if ~isempty(peakintervals)
                    %Error check user input
                    if mod(length(peakintervals),2)~=0
                        close all
                        f = errordlg('Uneven amount of endpoints specified. Please redo analysis and make sure to click an even amount of endpoints (ie 2 endpoints per peak)');
                        return
                    end
                    
                    numberofselectedpeaks=length(peakintervals)/2;
                    if app.amountofpeaks==numberofselectedpeaks
                        peakstartpoints=peakintervals(1:2:end)';
                        peakendpoints= peakintervals(2:2:end)';
                        
                        arrayofpeakstructs=struct('start',{},'end',{});
                        for j=1:length(peakstartpoints)
                            startofinterval=app.GetClosestIndexinVector(tracerdata,peakstartpoints(j));
                            endofinterval=app.GetClosestIndexinVector(tracerdata,peakendpoints(j));
                            
                            peak.start=startofinterval;
                            peak.end=endofinterval;
                            
                            arrayofpeakstructs(end+1)=peak;
                        end
                        
                        %Overwrite old peak interval data
                        app.SelectedPeaksMap(chromatogramfile)=arrayofpeakstructs;
                    else
                        errordlg('Please select the same amount of peaks specified from the start of the analysis.')
                        return
                    end
                end
                
            end
            
            %Update AUC for this adjusted chromatogram
            structofuserdefinedpeaks=app.SelectedPeaksMap(chromatogramfile);
            
            %Iterate through all peaks defined per chromatogram,
            %and calculate AUC (for each peak). Store into map
            arrayofAUCstructs=struct('AUC',{},'Time',[]);
            for j=1:length(structofuserdefinedpeaks)
                startofinterval=structofuserdefinedpeaks(j).start;
                endofinterval=structofuserdefinedpeaks(j).end;
                timeframe=(tracerdata.time(startofinterval)+tracerdata.time(endofinterval))/2;
                adjustedpeak.Time=timeframe;
                %Trapezoidal integration approximation
                %For more information:
                %   https://www.mathworks.com/help/matlab/ref/trapz.html
                %******************************************************************
                adjustedpeak.AUC=trapz(tracerdata.time(startofinterval:endofinterval),tracerdata.tic.values(startofinterval:endofinterval));
                %******************************************************************
                arrayofAUCstructs(end+1)=adjustedpeak;
                
            end
            app.AUCMap(chromatogramfile)=arrayofAUCstructs;


        end
        
        
        function PlotChromatogramsin3d(app)
            
            figure();
            xlabel('Time (secs)');
            ylabel('Frame Time Cross Section (mins)');
            zlabel('Parent Fraction (Unitless)');
            title('All Chromatograms');
            hold on;
            
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                timeofchromatogramsample=app.ExtractChromatogramTimeFromFileName(chromatogramfile);
                if timeofchromatogramsample~=-1
                    timeofdatacollection=tracerdata.time;
                    timeofchromatogram=[ones(1,length(timeofdatacollection))*timeofchromatogramsample]';
                    values=tracerdata.tic.values;
                    maxofvalues=max(values);
                    stem3(timeofdatacollection,timeofchromatogram,values/maxofvalues,'Color',[0.5 0.5 0.5],'Marker','.','Markersize',1);
                    view([18.8 10.8]);
                    
                    peaksforthischromatogram=app.SelectedPeaksMap(chromatogramfile);
                    
                    %Shade/color AUC of peak definition
                    for j=1:length(peaksforthischromatogram)
                        
                        starttime=peaksforthischromatogram(j).start;
                        endtime=peaksforthischromatogram(j).end;
                        
                        xnew=tracerdata.time(starttime:endtime);
                        ynew=[ones(1,length(xnew))*timeofchromatogramsample]';
                        
                        stem3(xnew,ynew,tracerdata.tic.values(starttime:endtime)/maxofvalues,'Markersize',1)
                        
                    end
                    
                else
                    errordlg=('Could not properly extract timing information from chromatogram folder name. Please recheck naming convention.')
                    return
                end
                
            end


        end
        
        function extractedtime=ExtractChromatogramTimeFromFileName(app,filename)
            patterntosearch='[0-9]+( *)?(M|m)(I|i)(N|n)(ute|UTE|utes|UTES)?';
            match=regexp(filename,patterntosearch,"match");
            if ~isempty(match)
                extractedtimecell=regexp(match{1},'[0-9]+',"match");
                extractedtime=str2num(extractedtimecell{1});
            else
                extractedtime=-1;
                return
            end
            
        end
        
        function WriteParentFunctionValuestoText(app)
        
            mapkeys=keys(app.RadiotracerdataMap);
            %Store parent fraction value for each chromatogram
            time=[];
            parentfraction=[];
            
            parenttimeinterval=app.ListBox_2.Value;
            parentpeak=app.PeaksNumberingConventionMap(parenttimeinterval);
            
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                chromatogramtime=app.ExtractChromatogramTimeFromFileName(chromatogramfile);
                
                structofAUCs=app.AUCMap(chromatogramfile);
                sumofAUC=0;
                for j=1:length(structofAUCs)
                    sumofAUC=sumofAUC+structofAUCs(j).AUC;
                end
                
                parentfractionpercent=(structofAUCs(parentpeak).AUC/sumofAUC)*100;
                
                time(i)=chromatogramtime;
                parentfraction(i)=parentfractionpercent;
                
            end
            
            time=[0,time];
            parentfraction=[100,parentfraction];
            
            %Sort out parent fraction times
            pfdata=[time;parentfraction]';
            pfdata=sortrows(pfdata,1);
        
            
            plot(app.UIAxes_2,time,parentfraction,'r*');
            app.UIAxes_2.XLabel.String = 'Time(mins)';
            app.UIAxes_2.YLabel.String = 'Parent Fraction (Unitless)';
            app.UIAxes_2.YLim = [0 100];
            
            
            parentfractiontextfile=fullfile(app.rootfolderpath,'ParentFraction.txt');
            fileID = fopen(parentfractiontextfile,'w');
            fprintf(fileID,'Time              Percent Fraction \n');
            Len=length(parentfraction);
            for i=1:Len
                fprintf(fileID,'%f          %f \n',pfdata(i,1),pfdata(i,2));
            end
            fclose(fileID);
            
            LinearInterpolationPlotWrite(parentfractiontextfile,app.rootfolderpath);
            HillFitPlotWrite(parentfractiontextfile,app.rootfolderpath);
            BiExponentialFitPlotWrite(parentfractiontextfile,app.rootfolderpath);
            LinearBiExponentialFitPlotWrite(parentfractiontextfile,app.rootfolderpath);
           
        end
        
        function CalculatePeakAUCforEachChromatogram(app)
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                
                structofuserdefinedpeaks=app.SelectedPeaksMap(chromatogramfile);
                
                %Iterate through all peaks defined per chromatogram,
                %and calculate AUC (for each peak). Store into map
                arrayofAUCstructs=struct('AUC',{},'Time',[]);
                for j=1:length(structofuserdefinedpeaks)
                    startofinterval=structofuserdefinedpeaks(j).start;
                    endofinterval=structofuserdefinedpeaks(j).end;
                    timeframe=(tracerdata.time(startofinterval)+tracerdata.time(endofinterval))/2;
                    peak.Time=timeframe;
                    %Trapezoidal integration approximation
                    %For more information: 
                    %   https://www.mathworks.com/help/matlab/ref/trapz.html
                    %******************************************************************
                    peak.AUC=trapz(tracerdata.time(startofinterval:endofinterval),tracerdata.tic.values(startofinterval:endofinterval));
                    %******************************************************************
                    arrayofAUCstructs(end+1)=peak;
                    
                    
                end
                app.AUCMap(chromatogramfile)=arrayofAUCstructs;
            end
            
        end
        
        
        function GenerateHPLCScreenshots(app)
            close all
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                try
                    peaksforthischromatogram=app.SelectedPeaksMap(chromatogramfile);
                catch ME
                    warndlg('Peaks not selected. Please complete step 4.')
                    break
                end
                
                figure(i);
                plot(tracerdata.time*60,tracerdata.tic.values);
                [~,filename]=fileparts(chromatogramfile);
                
                temp = strfind(filename,'_');
                filename(temp)= '-';
                title(filename);
                xlabel('Time (secs)');
                ylabel('Radioactivity');
                xlim = [0 tracerdata.time(end)*60];
                hold on;
                
                %Shade/color AUC of peak definition
                for j=1:length(peaksforthischromatogram)
                    starttime=peaksforthischromatogram(j).start;
                    endtime=peaksforthischromatogram(j).end;
                    areatohighlight=area(tracerdata.time(starttime:endtime)*60,tracerdata.tic.values(starttime:endtime))
                end
                
                %Save screenshot images
                saveas(gca,[fullfile(app.rootfolderpath,sprintf('%s_processedChromatogram.png',filename))]);
              
            end
            
        end
        
        function WriteAUCProportion(app)
            
            fileID = fopen(fullfile(app.rootfolderpath,'AreaUnderCurve.txt'),'w');
           
            mapkeys=keys(app.RadiotracerdataMap);

            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                try
                    structofAUCs=app.AUCMap(chromatogramfile);
                catch ME
                    warndlg('Peaks not selected. Please complete step 4.')
                    break
                end
                
                fprintf(fileID,'%s \n',chromatogramfile);
                fprintf(fileID,'       HPLC Results    (Manual)             \n');
                fprintf(fileID,'-------------------------------------------\n\n');
                fprintf(fileID,'     Peak Number     Time(secs)     AUC     ParentFraction')
                fprintf(fileID,'\n');
                fprintf(fileID,'\n');
                
                sumofAUC=0;
                %Get total AUC
                for j=1:length(structofAUCs)
                    sumofAUC=sumofAUC+structofAUCs(j).AUC;
                end
                
                %Calculate
                for j=1:length(structofAUCs)
                    timeframe=structofAUCs(j).Time;
                    fprintf(fileID,'\n      %i         %f        %f        ',j,timeframe,structofAUCs(j).AUC);
                    fprintf(fileID,'    %f \n',(structofAUCs(j).AUC/(sumofAUC))*100);
                    fprintf(fileID,'\n');
                    fprintf(fileID,'\n');
                end
                
            end
            fclose(fileID)
            
        end
        
        function ClearWorkspace(app)
            
            close all
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_2,'reset');
            
            app.RadiotracerdataMap=containers.Map();
            app.UnprocessedRadiotracerdataMap=containers.Map();
            app.SelectedPeaksMap=containers.Map();
            app.AUCMap=containers.Map();
            
            %Reset text boxes
            for i=1:10
                app.ListBox.Items{i}= sprintf('Item %d',i)
                app.ListBox_2.Items{i}=  sprintf('Item %d',i)
                app.ListBox_4.Items{i}= sprintf('Item %d',i)
            end
            
            %Reset Plots
            app.SmoothnessParameterEditField.Value=1000
            app.AsymmetricParameterEditField.Value=.9
            
            plot(app.UIAxes,[],[]);
            title(app.UIAxes, "Chromatogram Data")
            app.UIAxes.XLabel.String = 'Time (secs)';
            app.UIAxes.YLabel.String = 'Radioactivity';
            title(app.UIAxes_2, "Parent Fraction")
            app.UIAxes_2.XLabel.String = 'Time (mins)';
            app.UIAxes_2.YLabel.String = 'Parent Fraction (Unitless)';
            
        end
        
        function Phase1Files(app)
            %Create output folders
            app.CreateChromatogramFolders();
            
            ProcessedChromatogramFolder=fullfile(app.rootfolderpath,'ProcessedChromatograms');
            ParentFractionFolder=fullfile(app.rootfolderpath,'ParentFractionFits');
            ProcessedChromatogramValuesFolder=fullfile(app.rootfolderpath,'ProcessedChromatogramValues');
            RawChromatogramValuesFolder=fullfile(app.rootfolderpath,'RawChromatogramValues');
            
            %Move around files
            movefile(fullfile(app.rootfolderpath,'proc_*'),ParentFractionFolder);
            movefile(fullfile(app.rootfolderpath,'*processedChromatogram.png'),ProcessedChromatogramFolder);
            movefile(fullfile(app.rootfolderpath,'*ProcessedChromatogramActivityValues.txt'),ProcessedChromatogramValuesFolder);
            movefile(fullfile(app.rootfolderpath,'*RawChromatogramActivityValues.txt'),RawChromatogramValuesFolder);
            
        end
        
        function CreateChromatogramFolders(app)
            mkdir(sprintf('%s/ProcessedChromatograms',app.rootfolderpath));
            mkdir(sprintf('%s/ParentFractionFits',app.rootfolderpath));
            mkdir(sprintf('%s/ProcessedChromatogramValues',app.rootfolderpath)); 
            mkdir(sprintf('%s/RawChromatogramValues',app.rootfolderpath)); 
        end
        
        function UploadUncorrectedPlasma(app)
            plasmafiles=uipickfiles
            for i=1:length(plasmafiles)
                app.ListBox_4.Items{i}=plasmafiles{i};
                app.UncorrectedPlasma{i}=plasmafiles{i};
            end
       
        end
        
        function UploadParentFraction(app)
            parentfractionpath=uipickfiles;
            [~,filename,~]=fileparts(parentfractionpath{1});
            if filename ~= "ParentFraction"
                f = errordlg('User not enter raw Parent Fraction text file. Please input "ParentFraction.txt"');
                return
            end
            app.EditFieldPMOD.Value=parentfractionpath{1};
        end
        
        function CorrectBloodData(app)
            for i=1:length(app.UncorrectedPlasma)
                
                uncorrectedplasmafilepath=app.UncorrectedPlasma{i};
                UncorrectedBldLinearInterpolationPlotWrite(uncorrectedplasmafilepath,app.EditFieldPMOD.Value);
                UncorrectedBldHillFitPlotWrite(uncorrectedplasmafilepath,app.EditFieldPMOD.Value);
                UncorrectedBldBiExponentialFitPlotWrite(uncorrectedplasmafilepath,app.EditFieldPMOD.Value);
                UncorrectedBldLinearBiExponentialFitPlotWrite(uncorrectedplasmafilepath,app.EditFieldPMOD.Value);
                 
            end
        end
        
        function SortPhase2Files(app)
            for i=1:length(app.UncorrectedPlasma)
                uncorrectedplasmafilepath=app.UncorrectedPlasma{i};
                [rootpath,filename,~] = fileparts(uncorrectedplasmafilepath);
                foldername=fullfile(rootpath,sprintf('%s_output',filename));
                mkdir(foldername);
                movefile(sprintf('%s/%s*Corrected*',rootpath,filename),foldername);
            end
        end
        
        function PlotUncorrectedBloodPlasma(app)
        
            uncorrectedplasmafilepath=app.ListBox_4.Value;
            uncorrectedblood=dlmread(uncorrectedplasmafilepath,'',1,0);
            uncorrectedblood(:,1)=uncorrectedblood(:,1)/60;
            time=uncorrectedblood(:,1);   %sampling times
            plot(app.UIAxes3,time,uncorrectedblood(:,2),'b','LineWidth',1.25);
            
        end
        
        %Processed chromatogram values include shifting to baseline, decay
        %correcting, and smoothing
        function WriteProcessedChromatogramValues(app)
            mapkeys=keys(app.RadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.RadiotracerdataMap(chromatogramfile);
                
                [~,name,~]=fileparts(chromatogramfile);
                
                %Write out text files
                out = fullfile(app.rootfolderpath,sprintf('%s_ProcessedChromatogramActivityValues.txt',name));
                fileID = fopen(out,'w');
                fprintf(fileID,'Time               Shifted + DecayCorrected + Smoothed \n');
                Len=length(tracerdata.time);
                for i=1:Len
                    fprintf(fileID,'%f           %f\n',tracerdata.time(i)*60,tracerdata.tic.values(i));   
                end
                fclose(fileID);
            end
        end
        
        function WriteUnprocessedChromatogramValues(app)
            mapkeys=keys(app.UnprocessedRadiotracerdataMap);
            for i=1:length(mapkeys)
                chromatogramfile=mapkeys{i};
                tracerdata=app.UnprocessedRadiotracerdataMap(chromatogramfile);
                
                [~,name,~]=fileparts(chromatogramfile);
                
                %Write out text files
                out = fullfile(app.rootfolderpath,sprintf('%s_RawChromatogramActivityValues.txt',name));
                fileID = fopen(out,'w');
                fprintf(fileID,'Time               Raw Chromatogram Values \n');
                Len=length(tracerdata.time);
                for i=1:Len
                    fprintf(fileID,'%f           %f\n',tracerdata.time(i)*60,tracerdata.tic.values(i));   
                end
                fclose(fileID);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initializetoolbox=Chromatography();
        end

        % Button pushed function: LoadFilesButton
        function LoadFilesButtonPushed(app, event)
            
            agilentfolders=uipickfiles
            
            try
                %Upload folder/agilentdata key-value pairs into Map
                for i=1:length(agilentfolders)
                    foldername=agilentfolders{i};
                    [root,folder,ext] = fileparts(foldername); %Store only the folder name, not full path
                    chromatogramfile=fullfile(root,sprintf('%s%s',folder,ext));
                    app.rootfolderpath=root;
                    isValidfoldername=contains(foldername,'.D');
                    if isValidfoldername
                        importdatastruct=app.initializetoolbox.import(foldername);
                        if ~isempty(importdatastruct.name)
                            %store unprocessed chromatogram data
                            app.UnprocessedRadiotracerdataMap(chromatogramfile)=importdatastruct;
                            app.RadiotracerdataMap(chromatogramfile)=importdatastruct;
                            app.PreprocessRadiotracer(chromatogramfile);
                        end
                    end
                end
                %Display files to user via listbox
                app.DisplayChromatogramFileNames();
                
                %Plot
                app.PlotChromatogramData();
                
            catch ME
                return
            end
            
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            try
                app.PlotChromatogramData();
            catch
                return
            end
        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            try
                app.ApplyNoiseReduction();
            catch
                return
            end
        end

        % Button pushed function: SelectPeaksButton
        function SelectPeaksButtonPushed(app, event)
            try
                app.GetChromatogramPeaks();
                app.PlotSelectedChromatogramPeaks();
                app.DisplayPeakIntervalTimes();
            catch
                return
            end
        end

        % Button pushed function: ManualEditPeakButton
        function ManualEditPeakButtonPushed(app, event)
            try
                close all
                app.ManualPeakTimeIntervalEdit();
                
            catch
                return
            end
        end

        % Button pushed function: Generate3DVisualizationButton
        function Generate3DVisualizationButtonPushed(app, event)
            try
        app.PlotChromatogramsin3d();
            catch
                return
            end
        end

        % Button pushed function: GenerateParentFractionButton
        function GenerateParentFractionButtonPushed(app, event)
            try
                app.CalculatePeakAUCforEachChromatogram();
                app.WriteParentFunctionValuestoText();
            catch
                return
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            try
                app.GenerateHPLCScreenshots();
                app.WriteAUCProportion();
                app.WriteUnprocessedChromatogramValues();
                app.WriteProcessedChromatogramValues();
                app.Phase1Files();
            catch
                return
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ClearWorkspace();
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            close all;
        end

        % Value changed function: AsymmetricParameterEditField
        function AsymmetricParameterEditFieldValueChanged(app, event)
            try
                value = app.AsymmetricParameterEditField.Value;
                mapkeys=keys(app.RadiotracerdataMap);
                for i=1:length(mapkeys)
                    chromatogramfile=mapkeys{i};
                    app.PreprocessRadiotracer(chromatogramfile);
                end
            catch ME
                return
            end

        end

        % Value changed function: SmoothnessParameterEditField
        function SmoothnessParameterEditFieldValueChanged(app, event)
            try
                
                value = app.SmoothnessParameterEditField.Value;
                mapkeys=keys(app.RadiotracerdataMap);
                for i=1:length(mapkeys)
                    chromatogramfile=mapkeys{i};
                    app.PreprocessRadiotracer(chromatogramfile);
                end
                
            catch ME
                return
            end

        end

        % Button pushed function: UncorrectedPlasmaActivityButton
        function UncorrectedPlasmaActivityButtonPushed(app, event)
            try
                app.UploadUncorrectedPlasma();
                app.PlotUncorrectedBloodPlasma();

            catch ME
                return
            end
        
        end

        % Button pushed function: InputParentFractiontxtpathButton
        function InputParentFractiontxtpathButtonPushed(app, event)
             try
                 app.UploadParentFraction();
             catch ME
                 return
             end
        end

        % Button pushed function: CorrectBloodDataButton
        function CorrectBloodDataButtonPushed(app, event)
             try
                 app.CorrectBloodData();
                 app.SortPhase2Files();
             catch ME
                 return
             end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
                
            cla(app.UIAxes3,'reset');
            
            title(app.UIAxes3, "Plasma Total Metabolite Corrected")
            app.UIAxes3.XLabel.String = 'Time (mins)';
            app.UIAxes3.YLabel.String = 'Radioactivity';
            for i=1:5
                app.ListBox_4.Items{i}= sprintf('Item %d',i)
            end
            app.EditFieldPMOD.Value='';
            
            close all
            
        end

        % Value changed function: ListBox_4
        function ListBox_4ValueChanged(app, event)
            try
                app.PlotUncorrectedBloodPlasma();
            catch ME
                return
            end
        end

        % Button pushed function: CloseAppButton
        function CloseAppButtonPushed(app, event)
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Phase1Tab = uitab(app.TabGroup);
            app.Phase1Tab.Title = 'Phase 1';
            app.UIAxes = uiaxes(app.Phase1Tab);
            title(app.UIAxes, 'Chromatogram Data')
            xlabel(app.UIAxes, 'Time (secs)')
            ylabel(app.UIAxes, 'Radioactivity')
            app.UIAxes_2 = uiaxes(app.Phase1Tab);
            title(app.UIAxes_2, 'Parent Fraction')
            xlabel(app.UIAxes_2, 'Time (mins)')
            ylabel(app.UIAxes_2, 'Parent Fraction (Unitless)')
            app.Step1Label = uilabel(app.Phase1Tab);
            app.Step1Label.Text = 'Step 1:';
            app.ChooseDataTypeLabel = uilabel(app.Phase1Tab);
            app.ChooseDataTypeLabel.Text = 'Choose Data Type';
            app.DataType = uidropdown(app.Phase1Tab);
            app.DataType.Items = {'C-11', 'F-18'};
            app.DataType.Value = 'C-11';
            app.Step2Label = uilabel(app.Phase1Tab);
            app.Step2Label.Text = 'Step 2:';
            app.LoadFilesButton = uibutton(app.Phase1Tab, 'push');
            app.LoadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFilesButtonPushed, true);
            app.LoadFilesButton.Text = 'Load Files';
            app.Step7Label = uilabel(app.Phase1Tab);
            app.Step7Label.Text = 'Step 7:';
            app.SelectPeaksButton = uibutton(app.Phase1Tab, 'push');
            app.SelectPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPeaksButtonPushed, true);
            app.SelectPeaksButton.Text = 'Select Peaks';
            app.ListBox = uilistbox(app.Phase1Tab);
            app.ListBox.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.OptionalStep4aADVANCEDLabel = uilabel(app.Phase1Tab);
            app.OptionalStep4aADVANCEDLabel.Text = 'Optional* Step 4a (ADVANCED):';
            app.ManualEditPeakButton = uibutton(app.Phase1Tab, 'push');
            app.ManualEditPeakButton.ButtonPushedFcn = createCallbackFcn(app, @ManualEditPeakButtonPushed, true);
            app.ManualEditPeakButton.Text = 'Manual Edit Peak';
            app.Step3HighlightChromatogramFoldertouseforPeakSelectionLabel = uilabel(app.Phase1Tab);
            app.Step3HighlightChromatogramFoldertouseforPeakSelectionLabel.Text = 'Step 3: Highlight Chromatogram Folder to use for Peak Selection   ';
            app.Generate3DVisualizationButton = uibutton(app.Phase1Tab, 'push');
            app.Generate3DVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @Generate3DVisualizationButtonPushed, true);
            app.Generate3DVisualizationButton.Text = 'Generate 3D Visualization';
            app.Step4Label = uilabel(app.Phase1Tab);
            app.Step4Label.Text = 'Step 4:';
            app.ChooseTimeIntervalthatRepresentsParentPeakLabel = uilabel(app.Phase1Tab);
            app.ChooseTimeIntervalthatRepresentsParentPeakLabel.Text = 'Choose Time Interval that Represents Parent Peak ';
            app.ResetButton = uibutton(app.Phase1Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CloseAppButton = uibutton(app.Phase1Tab, 'push');
            app.CloseAppButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAppButtonPushed, true);
            app.CloseAppButton.Text = 'Close App';
            app.ListBox_2 = uilistbox(app.Phase1Tab);
            app.ListBox_2.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', 'Item 6', 'Item 7', 'Item 8', 'Item 9', 'Item 10', 'Item 11', 'Item 12', 'Item 13', 'Item 14', 'Item 15'};
            app.Step6Label = uilabel(app.Phase1Tab);
            app.Step6Label.Text = 'Step 6:';
            app.ExportDataButton = uibutton(app.Phase1Tab, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.Step5Label = uilabel(app.Phase1Tab);
            app.Step5Label.Text = 'Step 5:';
            app.GenerateParentFractionButton = uibutton(app.Phase1Tab, 'push');
            app.GenerateParentFractionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateParentFractionButtonPushed, true);
            app.GenerateParentFractionButton.Text = 'Generate Parent Fraction';
            app.AsymmetricParameterEditFieldLabel = uilabel(app.Phase1Tab);
            app.AsymmetricParameterEditFieldLabel.Text = 'Asymmetric Parameter: ';
            app.AsymmetricParameterEditField = uieditfield(app.Phase1Tab, 'numeric');
            app.AsymmetricParameterEditField.Limits = [0 1];
            app.AsymmetricParameterEditField.ValueChangedFcn = createCallbackFcn(app, @AsymmetricParameterEditFieldValueChanged, true);
            app.AsymmetricParameterEditField.Value = 0.9;
            app.SmoothnessParameterEditFieldLabel = uilabel(app.Phase1Tab);
            app.SmoothnessParameterEditFieldLabel.Text = 'Smoothness Parameter: ';
            app.SmoothnessParameterEditField = uieditfield(app.Phase1Tab, 'numeric');
            app.SmoothnessParameterEditField.Limits = [0 10000];
            app.SmoothnessParameterEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothnessParameterEditFieldValueChanged, true);
            app.SmoothnessParameterEditField.Value = 1000;
            app.OPTIONALStep2aADVANCEDLabel = uilabel(app.Phase1Tab);
            app.OPTIONALStep2aADVANCEDLabel.Text = 'OPTIONAL* Step 2a (ADVANCED):  ';
            app.Step8Label = uilabel(app.Phase1Tab);
            app.Step8Label.Text = 'Step 8:';
            app.TextArea = uitextarea(app.Phase1Tab);
            app.TextArea.Value = {'[0,1]'; ''};
            app.TextArea_2 = uitextarea(app.Phase1Tab);
            app.TextArea_2.Value = {'[0,10000]'; ''};
            app.CloseFiguresButton = uibutton(app.Phase1Tab, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Text = 'Close Figures';
            app.TextArea_3 = uitextarea(app.Phase1Tab);
            app.TextArea_3.Value = {'Smoothness Parameter: 1000'; ''};
            app.TextArea_4 = uitextarea(app.Phase1Tab);
            app.TextArea_4.Value = {'Asymmetric Parameter: 0.9'; ''; ''};
            app.NoiseButton = uibutton(app.Phase1Tab, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.OPTIONALStep3aADVANCEDLabel = uilabel(app.Phase1Tab);
            app.OPTIONALStep3aADVANCEDLabel.Text = 'OPTIONAL* Step 3a (ADVANCED):  ';
            app.Phase2Tab = uitab(app.TabGroup);
            app.Phase2Tab.Title = 'Phase 2';
            app.UIAxes3 = uiaxes(app.Phase2Tab);
            title(app.UIAxes3, 'Uncorrected Plasma Total Metabolite')
            xlabel(app.UIAxes3, 'Time (mins)')
            ylabel(app.UIAxes3, 'Radioactivity')
            app.UncorrectedPlasmaActivityButton = uibutton(app.Phase2Tab, 'push');
            app.UncorrectedPlasmaActivityButton.ButtonPushedFcn = createCallbackFcn(app, @UncorrectedPlasmaActivityButtonPushed, true);
            app.UncorrectedPlasmaActivityButton.Text = 'Uncorrected Plasma Activity';
            app.EditField = uieditfield(app.Phase2Tab, 'text');
            app.EditField.Value = '(Assumed input time is in seconds)';
            app.InputParentFractiontxtpathButton = uibutton(app.Phase2Tab, 'push');
            app.InputParentFractiontxtpathButton.ButtonPushedFcn = createCallbackFcn(app, @InputParentFractiontxtpathButtonPushed, true);
            app.InputParentFractiontxtpathButton.Text = 'Input "ParentFraction.txt" path';
            app.EditField_2 = uieditfield(app.Phase2Tab, 'text');
            app.EditField_2.Value = '(Assumed input time is in minutes)';
            app.EditFieldPMOD = uieditfield(app.Phase2Tab, 'text');
            app.ClearButton = uibutton(app.Phase2Tab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CorrectBloodDataButton = uibutton(app.Phase2Tab, 'push');
            app.CorrectBloodDataButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectBloodDataButtonPushed, true);
            app.CorrectBloodDataButton.Text = 'Correct Blood Data';
            app.Step1InputUncorrectedPlasmaBloodFilePathsLabel = uilabel(app.Phase2Tab);
            app.Step1InputUncorrectedPlasmaBloodFilePathsLabel.Text = 'Step1: Input Uncorrected Plasma Blood File Path(s) ';
            app.Step2InputParentFractionFilePathtxtorbldLabel = uilabel(app.Phase2Tab);
            app.Step2InputParentFractionFilePathtxtorbldLabel.Text = 'Step2: Input Parent Fraction File Path (.txt or bld) ';
            app.Step3CorrectPlasmaBloodFileLabel = uilabel(app.Phase2Tab);
            app.Step3CorrectPlasmaBloodFileLabel.Text = 'Step3: Correct Plasma Blood File';
            app.ListBox_4 = uilistbox(app.Phase2Tab);
            app.ListBox_4.ValueChangedFcn = createCallbackFcn(app, @ListBox_4ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AgilentHPLC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end